classdef app1_LS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        w_stopEditField                matlab.ui.control.NumericEditField
        w_stopEditFieldLabel           matlab.ui.control.Label
        w_transEditField               matlab.ui.control.NumericEditField
        w_transEditFieldLabel          matlab.ui.control.Label
        w_passEditField                matlab.ui.control.NumericEditField
        w_passEditFieldLabel           matlab.ui.control.Label
        stopband_cutoffEditField       matlab.ui.control.NumericEditField
        stopband_cutoffEditFieldLabel  matlab.ui.control.Label
        scale_typeDropDown             matlab.ui.control.DropDown
        scale_typeDropDownLabel        matlab.ui.control.Label
        methodDropDown                 matlab.ui.control.DropDown
        methodDropDownLabel            matlab.ui.control.Label
        passband_cutoffEditField       matlab.ui.control.NumericEditField
        passband_cutoffEditFieldLabel  matlab.ui.control.Label
        num_pointsEditField            matlab.ui.control.NumericEditField
        num_pointsEditFieldLabel       matlab.ui.control.Label
        FilterLengthInputEditField     matlab.ui.control.NumericEditField
        FilterLengthInputEditFieldLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    % Fixed audio file path
    fixedAudioPath = 'Al2nas.wav';

    % Load audio file
    try
        [audioSignal, fs] = audioread(fixedAudioPath);
        if size(audioSignal, 2) > 1
            audioSignal = audioSignal(:, 1); % Use first channel if stereo
        end
    catch
        uialert(app.UIFigure, 'Failed to load the fixed audio file. Check the file path.', 'File Error');
        return;
    end

    % Step 1: Upsample the audio file by a factor of 2
    upsampledSignal = resample(audioSignal, 2, 1);
    
    % Step 2: Frequency spectrum before and after upsampling
    figure;
    
    % Subplot 1: Frequency response before upsampling using freqz
    freqz(audioSignal, 1, 1024, fs);
    title('Freqz: Before Upsampling');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude (dB)');

    figure;
    % Subplot 2: Frequency response after upsampling using freqz
    freqz(upsampledSignal, 1, 1024, fs * 2);
    title('Freqz: After Upsampling');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude (dB)');

    % Subplot 3: FFT spectrum before upsampling
    figure;
    subplot(2, 1, 1);
    N = length(audioSignal);
    f = (0:N-1)*(fs/N); % Frequency axis
    magnitudeSpectrum = abs(fft(audioSignal));
    plot(f(1:N/2), magnitudeSpectrum(1:N/2));
    title('FFT: Before Upsampling');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude');

    % Subplot 4: FFT spectrum after upsampling
    subplot(2, 1, 2);
    N = length(upsampledSignal);
    f = (0:N-1)*(fs*2/N); % Frequency axis for upsampled signal
    magnitudeSpectrum = abs(fft(upsampledSignal));
    plot(f(1:N/2), magnitudeSpectrum(1:N/2));
    title('FFT: After Upsampling');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude');

    % Step 3: Add a sinusoidal interference signal
    t = (0:length(upsampledSignal)-1)' / (fs * 2); % Time vector for upsampled signal
    interferenceFreq = 3000; % Frequency of the interference signal (adjustable)
    interferenceSignal = 0.1 * sin(2 * pi * interferenceFreq * t); % Amplitude = 0.1

    % Add interference
    signalWithInterference = upsampledSignal + interferenceSignal;

    % Step 4: Plot the time-domain signals with distinct colors
    figure;
    plot(t, upsampledSignal, 'b'); % Blue for the audio signal
    hold on; % Hold the current plot
    plot(t, interferenceSignal, 'r'); % Red for the interference signal
    hold off; % Release the plot hold

    % Add legend
    legend({'Audio Signal','Interference Signal'});
    title('Time Domain Signal with Interference');

    % Step 5: Plot frequency spectrum after adding interference
    figure;
    N = length(signalWithInterference);
    f = (0:N-1)*(fs*2/N); % Frequency axis (upsampled frequency)
    magnitudeSpectrum = abs(fft(signalWithInterference)); % FFT and magnitude
    plot(f(1:N/2), magnitudeSpectrum(1:N/2)); % Plot positive frequencies
    title('Frequency Spectrum After Adding Interference');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude');


    % Step 7: Design a digital FIR filter using the filter design tool
    h = Loops_ls_wls_filter_design_gui(app.FilterLengthInputEditField.Value, app.num_pointsEditField.Value, app.methodDropDown.Value, app.scale_typeDropDown.Value, app.passband_cutoffEditField.Value, app.stopband_cutoffEditField.Value, app.w_passEditField.Value, app.w_transEditField.Value, app.w_stopEditField.Value);

    % Normalize the filter coefficients to prevent amplification
    h = h / sum(h);  % Normalize filter coefficients

    % Step 8: Apply the filter to remove interference from the signal
    filteredSignal = filter(h, 1, signalWithInterference);

    % Step 9: Plot the frequency spectrum of the filtered signal
    figure;
    N = length(filteredSignal);  % Number of samples
    f = (0:N-1)*(fs*2/N);  % Frequency axis
    magnitudeSpectrum = abs(fft(filteredSignal));  % FFT and magnitude
    plot(f(1:N/2), magnitudeSpectrum(1:N/2));  % Plot positive frequencies
    title('Frequency Spectrum After Filtering');
    xlabel('Frequency (Hz)');
    ylabel('Magnitude');

    % Plot frequency response of the designed filter
    figure;
    freqz(h, 1, 1024, fs * 2);  % Plot frequency response of the designed filter
    title('Frequency Response of the Designed Filter');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FilterLengthInputEditFieldLabel = uilabel(app.UIFigure);
            app.FilterLengthInputEditFieldLabel.Text = 'Filter Length Input';
            app.FilterLengthInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.num_pointsEditFieldLabel = uilabel(app.UIFigure);
            app.num_pointsEditFieldLabel.Text = 'num_points';
            app.num_pointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.passband_cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.passband_cutoffEditFieldLabel.Text = 'passband_cutoff';
            app.passband_cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.methodDropDownLabel = uilabel(app.UIFigure);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.UIFigure);
            app.methodDropDown.Items = {'LS', 'WLS'};
            app.methodDropDown.Value = 'LS';
            app.scale_typeDropDownLabel = uilabel(app.UIFigure);
            app.scale_typeDropDownLabel.Text = 'scale_type';
            app.scale_typeDropDown = uidropdown(app.UIFigure);
            app.scale_typeDropDown.Items = {'linear', 'log'};
            app.scale_typeDropDown.Value = 'linear';
            app.stopband_cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.stopband_cutoffEditFieldLabel.Text = 'stopband_cutoff';
            app.stopband_cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.w_passEditFieldLabel = uilabel(app.UIFigure);
            app.w_passEditFieldLabel.Text = 'w_pass';
            app.w_passEditField = uieditfield(app.UIFigure, 'numeric');
            app.w_transEditFieldLabel = uilabel(app.UIFigure);
            app.w_transEditFieldLabel.Text = 'w_trans';
            app.w_transEditField = uieditfield(app.UIFigure, 'numeric');
            app.w_stopEditFieldLabel = uilabel(app.UIFigure);
            app.w_stopEditFieldLabel.Text = 'w_stop';
            app.w_stopEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_LS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end